classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CantidadTotal               matlab.ui.control.Label
        Prueba                      matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        Panel_2                     matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        EnergaProducidaButton       matlab.ui.control.Button
        InstalacinButton            matlab.ui.control.Button
        CalculadoradeConsumoButton  matlab.ui.control.Button
        SeleccioneDropDown          matlab.ui.control.DropDown
        SeleccioneDropDownLabel     matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        ModificarButton             matlab.ui.control.Button
        LimpiarButton               matlab.ui.control.Button
        EliminarButton              matlab.ui.control.Button
        AgregarButton               matlab.ui.control.Button
        CalcularPotenciaButton      matlab.ui.control.Button
        ResultadoLabel              matlab.ui.control.Label
        CantidadDiasEditField       matlab.ui.control.NumericEditField
        DiaspromediodeusoalasemanaEditFieldLabel  matlab.ui.control.Label
        UsoEnHorasEditField         matlab.ui.control.NumericEditField
        UsopromediodiarioenhorasEditFieldLabel  matlab.ui.control.Label
        PotenciaEnWattsEditField    matlab.ui.control.NumericEditField
        PotenciaqueconsumeenWattsEditFieldLabel  matlab.ui.control.Label
        NombreEditField             matlab.ui.control.EditField
        NombreEditFieldLabel        matlab.ui.control.Label
        TipoDropDown                matlab.ui.control.DropDown
        TipoDropDownLabel           matlab.ui.control.Label
        CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Total = 0;
        n_prods = 0;
        ultimo_consumo = 0;
        usuario = C_Usuario(1, "Paulo", "Arequipa");
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AgregarButton
        function AgregarCallback(app, event)
            
          app.ResultadoLabel.Visible = "on";
          try
            consumo = app.PotenciaEnWattsEditField.Value;
            horas = app.UsoEnHorasEditField.Value;
            dias = app.CantidadDiasEditField.Value;
            tipo = app.TipoDropDown.Value;
            nombre = app.NombreEditField.Value;
            producto = C_Electrodomestico(nombre,consumo, horas, dias, tipo);
            app.ultimo_consumo = producto.ConsumoTotal(1);
            app.Total = app.ultimo_consumo + app.Total;
            app.n_prods = app.n_prods + 1;
            message = "El consumo de este producto al mes es: "+ app.ultimo_consumo +" Watts";
            %agregar electrodomesticos al usuario
            app.usuario = app.usuario.Add_Electrodomestico(producto);
          catch exception
              message = exception.message;
          end
          app.ResultadoLabel.Text = message;
            
        end

        % Button pushed function: CalcularPotenciaButton
        function CalcularTotalCallback(app, event)
            consumoTotalStr = num2str("El total es: "+app.Total+" Watts");
            app.ResultadoLabel.Text = consumoTotalStr;
        end

        % Button pushed function: LimpiarButton
        function LimpiarCallback(app, event)
            app.ResultadoLabel.Visible = "off";
            app.Total = 0;
        end

        % Button pushed function: EliminarButton
        function EliminarCallback(app, event)
            app.Total = app.Total - app.ultimo_consumo;
        end

        % Button pushed function: ModificarButton
        function ModificarCallback(app, event)
            nombresElectrodomesticos = strings(1, app.n_prods);
            for i = 1:app.n_prods
                nombresElectrodomesticos(i) = app.usuario.electrodomesticos(i).nombre;
            end
            app.SeleccioneDropDown.Items = nombresElectrodomesticos;
            %falta iterar
            app.SeleccioneDropDown.Visible = "on";
            %app.CantidadTotal.Text  = num2str(app.n_prods);
            %for i = 0:app.n_prods
                %app.Prueba.Text = ['Electrodoméstico ' num2str(i) ': ' num2str(app.usuario.electrodomesticos(i).nombre)];
                %app.Prueba.Text = num2str(app.usuario.electrodomesticos(i).nombre);
            %end}%
            %app.SeleccioneDropDown.Items = {'Esencial', 'No Esencial', 'Altamente Esencial'};
            %app.usuario.electrodomesticos
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'logo.jpg';
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel = uilabel(app.UIFigure);
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel.Text = 'Calculadora de ROI en la implementación de energía solar vs eléctrica';
            app.Panel = uipanel(app.UIFigure);
            app.TipoDropDownLabel = uilabel(app.Panel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.TipoDropDown = uidropdown(app.Panel);
            app.TipoDropDown.Items = {'Esencial', 'No Esencial', 'Altamente Esencial'};
            app.TipoDropDown.Value = 'Esencial';
            app.NombreEditFieldLabel = uilabel(app.Panel);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.Panel, 'text');
            app.PotenciaqueconsumeenWattsEditFieldLabel = uilabel(app.Panel);
            app.PotenciaqueconsumeenWattsEditFieldLabel.Text = 'Potencia que consume (en Watts)';
            app.PotenciaEnWattsEditField = uieditfield(app.Panel, 'numeric');
            app.UsopromediodiarioenhorasEditFieldLabel = uilabel(app.Panel);
            app.UsopromediodiarioenhorasEditFieldLabel.Text = 'Uso promedio diario (en horas)';
            app.UsoEnHorasEditField = uieditfield(app.Panel, 'numeric');
            app.DiaspromediodeusoalasemanaEditFieldLabel = uilabel(app.Panel);
            app.DiaspromediodeusoalasemanaEditFieldLabel.Text = 'Dias promedio de uso a la semana';
            app.CantidadDiasEditField = uieditfield(app.Panel, 'numeric');
            app.ResultadoLabel = uilabel(app.Panel);
            app.ResultadoLabel.Visible = 'off';
            app.ResultadoLabel.Text = 'Resultado';
            app.CalcularPotenciaButton = uibutton(app.Panel, 'push');
            app.CalcularPotenciaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularTotalCallback, true);
            app.CalcularPotenciaButton.Text = 'Calcular Potencia';
            app.AgregarButton = uibutton(app.Panel, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarCallback, true);
            app.AgregarButton.Text = 'Agregar';
            app.EliminarButton = uibutton(app.Panel, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarCallback, true);
            app.EliminarButton.Text = 'Eliminar';
            app.LimpiarButton = uibutton(app.Panel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarCallback, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.ModificarButton = uibutton(app.Panel, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarCallback, true);
            app.ModificarButton.Text = 'Modificar';
            app.SeleccioneDropDownLabel = uilabel(app.UIFigure);
            app.SeleccioneDropDownLabel.Visible = 'off';
            app.SeleccioneDropDownLabel.Text = 'Seleccione';
            app.SeleccioneDropDown = uidropdown(app.UIFigure);
            app.SeleccioneDropDown.Editable = 'on';
            app.SeleccioneDropDown.Visible = 'off';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel_2);
            app.CalculadoradeConsumoButton = uibutton(app.GridLayout, 'push');
            app.CalculadoradeConsumoButton.Icon = 'calc.png';
            app.CalculadoradeConsumoButton.Text = 'Calculadora de Consumo';
            app.InstalacinButton = uibutton(app.GridLayout, 'push');
            app.InstalacinButton.Icon = 'panel.png';
            app.InstalacinButton.Text = 'Instalación';
            app.EnergaProducidaButton = uibutton(app.GridLayout, 'push');
            app.EnergaProducidaButton.Icon = 'energia.png';
            app.EnergaProducidaButton.Text = 'Energía Producida';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.jpg';
            app.Prueba = uilabel(app.UIFigure);
            app.CantidadTotal = uilabel(app.UIFigure);
            app.CantidadTotal.Text = 'Total';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end